classdef app12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotallButton                  matlab.ui.control.Button
        SimulationControls             simulink.ui.control.SimulationControls
        Lamp                           matlab.ui.control.Lamp
        SimulationProgress             simulink.ui.control.SimulationProgress
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        LoadtirfileButton              matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        SelecttimeseriesifyouhavecsvfileofinputsLabel  matlab.ui.control.Label
        CSV_FilenameLabel              matlab.ui.control.Label
        BrowseCSVButton                matlab.ui.control.Button
        CSV_FileEditField              matlab.ui.control.EditField
        CSV_FileEditFieldLabel         matlab.ui.control.Label
        TimeseriesButton               matlab.ui.control.Button
        VehicleparametersandInputsTab  matlab.ui.container.Tab
        ParTable                       matlab.ui.control.Table
        LoadplotsforInputsButton       matlab.ui.control.Button
        InputPanel                     matlab.ui.container.Panel
        Tire_analysisTab_2             matlab.ui.container.Tab
        LoadtirfileButton_2            matlab.ui.control.Button
        TireTable                      matlab.ui.control.Table
        TirePanel                      matlab.ui.container.Panel
        PlotTireButton                 matlab.ui.control.Button
        BrakesTab                      matlab.ui.container.Tab
        BrakePlotsButton               matlab.ui.control.Button
        Brake_ipt_pct                  matlab.ui.container.Panel
        BrakeTorquePanel               matlab.ui.container.Panel
        BrakeTable                     matlab.ui.control.Table
        SuspensionsTab                 matlab.ui.container.Tab
        PlotsusButton                  matlab.ui.control.Button
        RollandPitchPanel              matlab.ui.container.Panel
        WheelDisplacementPanel         matlab.ui.container.Panel
        SuspensionTable                matlab.ui.control.Table
        AerodynamicsTab                matlab.ui.container.Tab
        GenerateAeroPlotsButton        matlab.ui.control.Button
        AeroplotsPanel                 matlab.ui.container.Panel
        AeroTable                      matlab.ui.control.Table
        MotorTab                       matlab.ui.container.Tab
        MotorPanel                     matlab.ui.container.Panel
        MotorTable                     matlab.ui.control.Table
        PlotMotorButton                matlab.ui.control.Button
        BatteryTab                     matlab.ui.container.Tab
        BatteryPanel                   matlab.ui.container.Panel
        BatteryTable                   matlab.ui.control.Table
        PlotBatteryButton              matlab.ui.control.Button
        OutputsTab                     matlab.ui.container.Tab
        PlotoutputsButton              matlab.ui.control.Button
        OutputsPanel                   matlab.ui.container.Panel
        gg_per_wheelTab                matlab.ui.container.Tab
        GGV_per_wheelPanel             matlab.ui.container.Panel
        PlotGGButton                   matlab.ui.control.Button
        GGVandYawmomentTab             matlab.ui.container.Tab
        YawmomentdiagramplottedLabel   matlab.ui.control.Label
        PleasewaitLabel_2              matlab.ui.control.Label
        PlotYaw_Moment_diagramButton   matlab.ui.control.Button
        GGVplotgeneratedLabel          matlab.ui.control.Label
        PleasewaitLabel                matlab.ui.control.Label
        PlotGGVButton                  matlab.ui.control.Button
        UIAxes3D_2                     matlab.ui.control.UIAxes
        UIAxes3D                       matlab.ui.control.UIAxes
        MAPsTab                        matlab.ui.container.Tab
        TheplotisunderprogressLabel    matlab.ui.control.Label
        plot_mapButton                 matlab.ui.control.Button
        PlotCurvaturevsSteer_angleButton  matlab.ui.control.Button
        UIAxes20                       matlab.ui.control.UIAxes
        UIAxes15                       matlab.ui.control.UIAxes
        handlingcurveTab               matlab.ui.container.Tab
        Plot_handlingcurveButton       matlab.ui.control.Button
        UIAxes21                       matlab.ui.control.UIAxes
        LapSim_EnduranceTab            matlab.ui.container.Tab
        Plot_Lapsim_enduranceButton    matlab.ui.control.Button
        Browse_track_csv_for_EnduranceButton  matlab.ui.control.Button
        LaptimesimulatorforEndurancePanel  matlab.ui.container.Panel
        LapSim_AutocrossTab            matlab.ui.container.Tab
        Plot_LapSim_finalButton        matlab.ui.control.Button
        Browse_track_csvButton         matlab.ui.control.Button
        FinalLaptimesimulatorPanel     matlab.ui.container.Panel
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        Par
        sus_par
        aero_par 
        TireData
        Battery
        Motor
        Brake
        modelname = 'Vehicle_Model_999_Modular' % Description
        TrackFilePath_endurance % Description
        TrackFilePath_final 
    end

    methods (Access = private)
        % Function to plot Aero plots to Panel
        function plotAeroAnalysis(app, out)
            % 1. Setup Theme and Panel
            bgColor = [0.03, 0.03, 0.03]; 
            gridColor = [0.2, 0.2, 0.2]; 
            txtColor = [0.9, 0.9, 0.9];
            
            delete(app.AeroplotsPanel.Children); 
            
            % Create a 2x2 grid for the Aero/Performance Dashboard
            aeroLayout = tiledlayout(app.AeroplotsPanel, 2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.AeroplotsPanel.BackgroundColor = bgColor;
            
            % 2. Extract Data from 'out'
            time_vec = out.tout;
            % Using squeeze to handle potential 3D arrays from Simulink
            t_torque = squeeze(out.Total_wheel_torque.Data);
            v_vel    = squeeze(out.Longitudinal_vel.Data); % Assuming 'vx' is the primary velocity signal
            d_force  = squeeze(out.Downforce.Data);
            dr_force = squeeze(out.Dragforce.Data);
            
            % 3. Define Plotting Configuration
            data_list = {t_torque, d_force, v_vel, dr_force};
            titles    = {'Total Torque (Nm)', 'Downforce (N)', 'Vehicle Velocity (m/s)', 'Drag Force (N)'};
            colors    = {[0, 1, 1], [0.39, 1.0, 0.0], [1, 0, 1], [1, 1, 0]}; % Cyan, Lime, Magenta, Yellow
            y_labels  = {'Torque', 'Force', 'Velocity', 'Force'};
    
            % 4. Generate Tiles
            for i = 1:4
                ax = nexttile(aeroLayout);
                
                % Apply Styling
                ax.Color = bgColor;
                ax.XColor = txtColor; 
                ax.YColor = txtColor;
                ax.GridColor = gridColor;
                grid(ax, 'on');
                
                % Plot Data
                plot(ax, time_vec, data_list{i}, 'Color', colors{i}, 'LineWidth', 1.5);
                
                % Formatting
                title(ax, titles{i}, 'Color', 'w', 'FontSize', 10, 'FontWeight', 'bold');
                ylabel(ax, y_labels{i}, 'FontSize', 9);
                
                % Add X-labels only to the bottom row (Velocity and Dragforce)
                if i > 2
                    xlabel(ax, 'Time (s)', 'FontSize', 9);
                end
            end
        end

        function plotWheelDisplacement(app, out)
            % 1. Setup Theme and Panel
            bgColor = [0.03, 0.03, 0.03]; 
            gridColor = [0.2, 0.2, 0.2]; 
            txtColor = [0.9, 0.9, 0.9];
            
            % Delete existing plots to refresh the layout
            delete(app.WheelDisplacementPanel.Children); 
            
            % Create a 2x2 grid (Front-Left, Front-Right, Rear-Left, Rear-Right)
            dispLayout = tiledlayout(app.WheelDisplacementPanel, 2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.WheelDisplacementPanel.BackgroundColor = bgColor;
            
            % 2. Extract Data from 'out'
            time_vec = out.tout;
            z_data = {
                squeeze(out.FL_wheel_displacement.Data), squeeze(out.FR_wheel_displacement.Data), ...
                squeeze(out.RL_wheel_displacement.Data), squeeze(out.RR_wheel_displacement.Data)
            };
            
            titles = {'Front Left (zsfl)', 'Front Right (zsfr)', 'Rear Left (zsrl)', 'Rear Right (zsrr)'};
            % Using Lime for Front and Yellow for Rear to distinguish axles visually
            colors = {[0.39, 1.0, 0.0], [0.39, 1.0, 0.0], [1, 1, 0], [1, 1, 0]};
            
            % 3. Generate the Plots
            for i = 1:4
                ax = nexttile(dispLayout);
                
                % Styling
                ax.Color = bgColor;
                ax.XColor = txtColor; 
                ax.YColor = txtColor;
                ax.GridColor = gridColor;
                grid(ax, 'on');
                
                % Plotting the displacement over time
                plot(ax, time_vec, z_data{i}, 'Color', colors{i}, 'LineWidth', 1.5);
                
                % Formatting
                title(ax, titles{i}, 'Color', 'w', 'FontSize', 10);
                ylabel(ax, 'Disp (mm)', 'FontSize', 9);
                
                % Only show X-label on the bottom row
                if i > 2
                    xlabel(ax, 'Time (s)', 'FontSize', 9);
                end
            end
        end
    
        function plotTireAnalysis(app, out)
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            neonCyan = [0, 1, 1]; neonMag = [1, 0, 1];
            
            delete(app.TirePanel.Children); 
            tireLayout = tiledlayout(app.TirePanel, 2, 4, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.TirePanel.BackgroundColor = bgColor;
            
            K_data = {squeeze(out.slip_ratio_fl.Data), squeeze(out.slip_ratio_fr.Data), ...
                      squeeze(out.slip_ratio_rl.Data), squeeze(out.slip_ratio_rr.Data)};
            Fx_data = {squeeze(out.Longitudinal_force_fl.Data), squeeze(out.Longitudinal_force_fr.Data), ...
                       squeeze(out.Longitudinal_force_rl.Data), squeeze(out.Longitudinal_force_rr.Data)};
            alpha_data = {squeeze(out.slip_angle_fl.Data), squeeze(out.slip_angle_fr.Data), ...
                          squeeze(out.slip_angle_rl.Data), squeeze(out.slip_angle_rr.Data)};
            Fy_data = {squeeze(out.Lateral_force_fl.Data), squeeze(out.Lateral_force_fr.Data), ...
                       squeeze(out.Lateral_force_rl.Data), squeeze(out.Lateral_force_rr.Data)};
            wheel_names = {'FL', 'FR', 'RL', 'RR'};
    
            for i = 1:8
                ax = nexttile(tireLayout);
                ax.Color = bgColor; ax.XColor = txtColor; ax.YColor = txtColor;
                ax.GridColor = gridColor; grid(ax, 'on');
                if i <= 4
                    scatter(ax, K_data{i}, Fx_data{i}, 8, 'MarkerFaceColor', neonCyan, 'MarkerEdgeColor', 'none', 'MarkerFaceAlpha', 0.4);
                    title(ax, [wheel_names{i} ' - Fx vs \kappa'], 'Color', 'w');
                else
                    idx = i - 4;
                    scatter(ax, alpha_data{idx}, Fy_data{idx}, 8, 'MarkerFaceColor', neonMag, 'MarkerEdgeColor', 'none', 'MarkerFaceAlpha', 0.4);
                    title(ax, [wheel_names{idx} ' - Fy vs \alpha'], 'Color', 'w');
                end
            end
        end
    
        function plotMotorAnalysis(app, out)
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            neonCyan = [0, 1, 1]; neonMag = [1, 0, 1];
    
            delete(app.MotorPanel.Children); 
            motor_rpm = squeeze(out.motor_rpm.Data);
            motor_torque = squeeze(out.motor_torque.Data);
            P_mech = squeeze(out.Mechanical_power.Data);
            current = squeeze(out.Motor_current.Data);
            
            motLayout = tiledlayout(app.MotorPanel, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.MotorPanel.BackgroundColor = bgColor;
            
            ax1 = nexttile(motLayout, [2 1]); 
            scatter(ax1, current, motor_torque, 12, 'MarkerFaceColor', neonCyan, 'MarkerFaceAlpha', 0.6, 'MarkerEdgeColor', 'none');
            set(ax1, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax1, 'on');
            title(ax1, 'Torque vs Current', 'Color', 'w');
    
            ax2 = nexttile(motLayout, [1 2]); 
            scatter(ax2, motor_rpm, motor_torque, 10, 'MarkerFaceColor', neonCyan, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            set(ax2, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax2, 'on');
            title(ax2, 'Motor Torque vs Speed', 'Color', 'w');
    
            ax3 = nexttile(motLayout, [1 2]); 
            scatter(ax3, motor_rpm, P_mech, 10, 'MarkerFaceColor', neonMag, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            set(ax3, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax3, 'on');
            title(ax3, 'Power vs Speed', 'Color', 'w');
        end
    
        function plotBatteryAnalysis(app, out)
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            delete(app.BatteryPanel.Children); 
            batLayout = tiledlayout(app.BatteryPanel, 2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.BatteryPanel.BackgroundColor = bgColor;
            
            SoC = squeeze(out.SoC.Data);
            bat_y = {squeeze(out.R0_cell.Data), squeeze(out.R1_cell.Data), squeeze(out.cell_volt.Data), squeeze(out.Tou_1.Data)};
            bat_titles = {'R0 vs SoC', 'R1 vs SoC', 'Voltage vs SoC', 'Tau vs SoC'};
            colors = {[0,1,1], [1,0,1], [0.39,1,0], [1,1,0]};
    
            for i = 1:4
                ax = nexttile(batLayout);
                scatter(ax, SoC, bat_y{i}, 10, 'MarkerFaceColor', colors{i}, 'MarkerEdgeColor', 'none', 'MarkerFaceAlpha', 0.5);
                set(ax, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax, 'on');
                title(ax, bat_titles{i}, 'Color', 'w');
            end
        end
    
        function plotOutputsTelemetry(app, out)
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            delete(app.OutputsPanel.Children); 
            outLayout = tiledlayout(app.OutputsPanel, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.OutputsPanel.BackgroundColor = bgColor;
            
            data_list = {squeeze(out.Longitudinal_accln.Data), squeeze(out.Lateral_accln.Data), squeeze(out.Yaw_accln.Data), ...
                         squeeze(out.Longitudinal_vel.Data), squeeze(out.Lateral_vel.Data), squeeze(out.Yaw_rate.Data)};
            plot_titles = {'Long. Accel (m/s^2)', 'Lat. Accel (m/s^2)', 'Yaw Moment(Nm)', 'Vx(m/s)', 'Vy(m/s)', 'Yaw Rate(rad/s)'};
            colors = {[0,1,1], [0,1,1], [0.39,1,0], [1,0,1], [1,0,1], [1,1,0]};
    
            for i = 1:6
                ax = nexttile(outLayout);
                plot(ax, out.tout, data_list{i}, 'Color', colors{i}, 'LineWidth', 1.5);
                set(ax, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax, 'on');
                title(ax, plot_titles{i}, 'Color', 'w');
            end
        end
    
        function plotBrakingSystem(app, out)
            % Combines Brake Torque and Brake Pedal Input
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            
            % 1. Torque Plots
            delete(app.BrakeTorquePanel.Children);
            brakeLayout = tiledlayout(app.BrakeTorquePanel, 2, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.BrakeTorquePanel.BackgroundColor = bgColor;
            
            trq_data = {squeeze(out.Front_braking_torque.Data), squeeze(out.Rear_braking_torque.Data)};
            trq_titles = {'Front Braking Torque(Nm)', 'Rear Braking Torque(Nm)'};
            trq_colors = {[0,1,1], [1,0,1]};
    
            for i = 1:2
                ax = nexttile(brakeLayout);
                plot(ax, out.tout, trq_data{i}, 'Color', trq_colors{i}, 'LineWidth', 2);
                set(ax, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor); grid(ax, 'on');
                title(ax, trq_titles{i}, 'Color', 'w');
            end
    
            % 2. Pedal Input
            targetAx = findobj(app.Brake_ipt_pct, 'Type', 'axes');
            if isempty(targetAx), targetAx = axes(app.Brake_ipt_pct); end
            set(targetAx, 'Units', 'normalized', 'Position', [0.15 0.15 0.75 0.75], ...
                'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', [0.5, 0.5, 0.5]);
            cla(targetAx);
            plot(targetAx, out.tout, squeeze(out.Brake_input_percent.Data), 'Color', [1,1,0], 'LineWidth', 2);
            title(targetAx, 'Brake Pedal Input (%)', 'Color', 'w');
        end
    
        function plotInputPanel(app, out)
            bgColor = [0.03, 0.03, 0.03]; txtColor = [0.9, 0.9, 0.9];
            
            % Torque Plot
            axTorque = findobj(app.InputPanel, 'Tag', 'axTorque');
            if isempty(axTorque), axTorque = axes(app.InputPanel, 'Tag', 'axTorque'); end
            set(axTorque, 'Units', 'normalized', 'Position', [0.08, 0.20, 0.38, 0.65], ...
                'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor);
            cla(axTorque);
            plot(axTorque, out.tout, squeeze(out.Total_wheel_torque.Data), 'Color', [0,1,1]);
            title(axTorque, 'Total Torque (Nm)', 'Color', 'w');
    
            % Steering Plot
            axSteer = findobj(app.InputPanel, 'Tag', 'axSteer');
            if isempty(axSteer), axSteer = axes(app.InputPanel, 'Tag', 'axSteer'); end
            set(axSteer, 'Units', 'normalized', 'Position', [0.58, 0.20, 0.38, 0.65], ...
                'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor);
            cla(axSteer);
            plot(axSteer, out.tout, squeeze(out.Steering_wheel_angle_deg.Data), 'Color', [1,0,1]);
            title(axSteer, 'Steering Angle(deg)', 'Color', 'w');
        end

        function plotGGAnalysis(app, out)
            % 1. Styling Variables
            bgColor = [0.03, 0.03, 0.03]; gridColor = [0.2, 0.2, 0.2]; txtColor = [0.9, 0.9, 0.9];
            neonCyan = [0, 1, 1]; neonMag = [1, 0, 1]; neonYel = [1, 1, 0]; neonGrn = [0, 1, 0];
        
            % 2. Physics & Conversion Setup
            corner_mass = 270/4; % <-- Mass per corner in kg 
            g_conv = corner_mass * 9.81; % Divisor to convert Force (N) to g-forces
        
            % 3. Extract and Convert Forces (Fx, Fy) to G's
            gx_FL = squeeze(out.Longitudinal_force_fl.Data) / g_conv; gy_FL = squeeze(out.Lateral_force_fl.Data) / g_conv;
            gx_FR = squeeze(out.Longitudinal_force_fr.Data) / g_conv; gy_FR = squeeze(out.Lateral_force_fr.Data) / g_conv;
            gx_RL = squeeze(out.Longitudinal_force_rl.Data) / g_conv; gy_RL = squeeze(out.Lateral_force_rl.Data) / g_conv;
            gx_RR = squeeze(out.Longitudinal_force_rr.Data) / g_conv; gy_RR = squeeze(out.Lateral_force_rr.Data) / g_conv;
            
            % 4. Pre-calculate the Limit Ellipse Boundary (Converted to g's)
            theta = linspace(0, 2*pi, 200);
            max_lat_N = 1280;  % Your previously defined max Lateral Force in N
            max_long_N = 1360; % Your previously defined max Longitudinal Force in N
            ellipse_lat_g = (max_lat_N / g_conv) * cos(theta);
            ellipse_long_g = (max_long_N / g_conv) * sin(theta);
        
            % 5. Create 2x2 Layout
            delete(app.GGV_per_wheelPanel.Children); 
            ggLayout = tiledlayout(app.GGV_per_wheelPanel, 2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.GGV_per_wheelPanel.BackgroundColor = bgColor;
            
            % --- Front Left Plot ---
            ax1 = nexttile(ggLayout); 
            hold(ax1, 'on'); % Lock axes to layer plots
            plot(ax1, ellipse_lat_g, ellipse_long_g, 'w--', 'LineWidth', 1.2); % Boundary Line
            scatter(ax1, -gy_FL, gx_FL, 10, 'MarkerFaceColor', neonCyan, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            hold(ax1, 'off');
            % Dot notation properties
            ax1.Color = bgColor; ax1.XColor = txtColor; ax1.YColor = txtColor; ax1.GridColor = gridColor;
            ax1.XGrid = 'on'; ax1.YGrid = 'on';
            ax1.Title.String = 'Front Left (FL)'; ax1.Title.Color = 'w';
            ax1.XLabel.String = 'Lat Force (g)'; ax1.XLabel.Color = txtColor;
            ax1.YLabel.String = 'Long Force (g)'; ax1.YLabel.Color = txtColor;
            ax1.XLim = [-2.5, 2.5]; ax1.YLim = [-2.5, 2.5];
        
            % --- Front Right Plot ---
            ax2 = nexttile(ggLayout); 
            hold(ax2, 'on');
            plot(ax2, ellipse_lat_g, ellipse_long_g, 'w--', 'LineWidth', 1.2); 
            scatter(ax2,  -gy_FR, gx_FR,10, 'MarkerFaceColor', neonMag, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            hold(ax2, 'off');
            ax2.Color = bgColor; ax2.XColor = txtColor; ax2.YColor = txtColor; ax2.GridColor = gridColor;
            ax2.XGrid = 'on'; ax2.YGrid = 'on';
            ax2.Title.String = 'Front Right (FR)'; ax2.Title.Color = 'w';
            ax2.XLabel.String = 'Lat Force (g)'; ax2.XLabel.Color = txtColor;
            ax2.YLabel.String = 'Long Force (g)'; ax2.YLabel.Color = txtColor;
            ax2.XLim = [-2.5, 2.5]; ax2.YLim = [-2.5, 2.5];
        
            % --- Rear Left Plot ---
            ax3 = nexttile(ggLayout); 
            hold(ax3, 'on');
            plot(ax3, ellipse_lat_g, ellipse_long_g, 'w--', 'LineWidth', 1.2); 
            scatter(ax3, -gy_RL, gx_RL, 10, 'MarkerFaceColor', neonYel, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            hold(ax3, 'off');
            ax3.Color = bgColor; ax3.XColor = txtColor; ax3.YColor = txtColor; ax3.GridColor = gridColor;
            ax3.XGrid = 'on'; ax3.YGrid = 'on';
            ax3.Title.String = 'Rear Left (RL)'; ax3.Title.Color = 'w';
            ax3.XLabel.String = 'Lat Force (g)'; ax3.XLabel.Color = txtColor;
            ax3.YLabel.String = 'Long Force (g)'; ax3.YLabel.Color = txtColor;
            ax3.XLim = [-2.5, 2.5]; ax3.YLim = [-2.5, 2.5];
        
            % --- Rear Right Plot ---
            ax4 = nexttile(ggLayout); 
            hold(ax4, 'on');
            plot(ax4, ellipse_lat_g, ellipse_long_g, 'w--', 'LineWidth', 1.2); 
            scatter(ax4, -gy_RR, gx_RR, 10, 'MarkerFaceColor', neonGrn, 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', 'none');
            hold(ax4, 'off');
            ax4.Color = bgColor; ax4.XColor = txtColor; ax4.YColor = txtColor; ax4.GridColor = gridColor;
            ax4.XGrid = 'on'; ax4.YGrid = 'on';
            ax4.Title.String = 'Rear Right (RR)'; ax4.Title.Color = 'w';
            ax4.XLabel.String = 'Lat Force (g)'; ax4.XLabel.Color = txtColor;
            ax4.YLabel.String = 'Long Force (g)'; ax4.YLabel.Color = txtColor;
            ax4.XLim = [-2.5, 2.5]; ax4.YLim = [-2.5, 2.5];
            
            % 6. Link axes to ensure proportionality
            linkaxes([ax1, ax2, ax3, ax4], 'xy');
        end

        function plotRollAndPitch(app, out)
            % 1. Setup Theme and Panel
            bgColor = [0.03, 0.03, 0.03]; 
            gridColor = [0.2, 0.2, 0.2]; 
            txtColor = [0.9, 0.9, 0.9];
            neonCyan = [0, 1, 1]; % Roll color
            neonMag  = [1, 0, 1]; % Pitch color
            
            delete(app.RollandPitchPanel.Children); 
            
            % Create a 2x1 grid for vertical comparison
            rpLayout = tiledlayout(app.RollandPitchPanel, 1, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.RollandPitchPanel.BackgroundColor = bgColor;
            
            % 2. Extract Data
            time_vec = out.tout;
            roll_data = squeeze(out.Body_roll_angle.Data);
            pitch_data = squeeze(out.Body_pitch_angle.Data);
            
            % --- TOP TILE: ROLL ---
            axRoll = nexttile(rpLayout);
            plot(axRoll, time_vec, roll_data, 'Color', neonCyan, 'LineWidth', 2);
            set(axRoll, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor);
            grid(axRoll, 'on');
            title(axRoll, 'Vehicle Roll Angle (deg)', 'Color', 'w', 'FontSize', 11);
            ylabel(axRoll, 'Roll [deg]');
            axRoll.XTickLabel = []; % Hide X-labels on top plot for cleaner look
            
            % --- BOTTOM TILE: PITCH ---
            axPitch = nexttile(rpLayout);
            plot(axPitch, time_vec, pitch_data, 'Color', neonMag, 'LineWidth', 2);
            set(axPitch, 'Color', bgColor, 'XColor', txtColor, 'YColor', txtColor, 'GridColor', gridColor);
            grid(axPitch, 'on');
            title(axPitch, 'Vehicle Pitch Angle (deg)', 'Color', 'w', 'FontSize', 11);
            ylabel(axPitch, 'Pitch [deg]');
            xlabel(axPitch, 'Time (s)');
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin("base","Mode",1)
            app.TimeseriesButton.Enable = "off";
            app.TheplotisunderprogressLabel.Visible = "off";

            % Load parameter files OR copy from base workspace once
            app.Par       = evalin('base','Par');
            app.sus_par   = evalin('base','sus_par');
            app.aero_par  = evalin('base','aero_par');
            %% Create table for Battery parameters
            % 1. Get the Battery struct from the workspace
                battStruct = evalin('base', 'Battery');
                fields = fieldnames(battStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything into text so the table can read it
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = battStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. matrices
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Converts 7x3 matrices into readable text
                    end
                end
                
                % 4. Push to the UI Table
                app.BatteryTable.Data = tableData;
                app.BatteryTable.ColumnName = {'Parameter', 'Value'};

                %% Create table for TireData:
                % 1. Get TireData from base workspace
                tireStruct = evalin('base', 'TireData');
                
                % Extract main fields
                mainFields = fieldnames(tireStruct);
                
                % Create an empty cell array (make it large enough to hold all variables)
                tableData = {};
                rowIndex = 1;
                
                % 2. Loop through and flatten the struct
                for i = 1:length(mainFields)
                    paramName = mainFields{i};
                    val = tireStruct.(paramName);
                    
                    if isstruct(val) && strcmp(paramName, 'Scales')
                        % It's the nested Scales struct, unroll it!
                        scaleFields = fieldnames(val);
                        for j = 1:length(scaleFields)
                            tableData{rowIndex, 1} = ['Scales.', scaleFields{j}];
                            tableData{rowIndex, 2} = num2str(val.(scaleFields{j}));
                            rowIndex = rowIndex + 1;
                        end
                    elseif isstruct(val)
                        % Ignore any other random nested structs just in case
                        continue;
                    else
                        % It's a normal parameter
                        tableData{rowIndex, 1} = paramName;
                        if ischar(val) || isstring(val)
                            tableData{rowIndex, 2} = char(val);
                        elseif isscalar(val) && isnumeric(val)
                            tableData{rowIndex, 2} = num2str(val);
                        else
                            tableData{rowIndex, 2} = mat2str(val);
                        end
                        rowIndex = rowIndex + 1;
                    end
                end
                
                % 3. Push to the UI Table
                app.TireTable.Data = tableData;
                app.TireTable.ColumnName = {'Parameter', 'Value'};
                
                %% Create table for vehicle parameters
                % --- LOAD VEHICLE PARAMETERS (Par) ---
                % 1. Get the Par struct from the workspace
                parStruct = evalin('base', 'Par');
                fields = fieldnames(parStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = parStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. matrices (like CG coordinates)
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Safely handles arrays/vectors
                    end
                end
                
                % 4. Push to the UI Table
                app.ParTable.Data = tableData;
                app.ParTable.ColumnName = {'Parameter', 'Value'};

                %% Create table for Brakes
                % --- LOAD BRAKE PARAMETERS (Brake) ---
                % 1. Get the Brake struct from the workspace
                brakeStruct = evalin('base', 'Brake');
                fields = fieldnames(brakeStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything into text format
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = brakeStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. arrays
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Safely handles arrays like bias curves
                    end
                end
                
                % 4. Push to the UI Table
                app.BrakeTable.Data = tableData;
                app.BrakeTable.ColumnName = {'Parameter', 'Value'};

                %% Create Table for Suspension parameters
                % --- LOAD SUSPENSION PARAMETERS (sus_par) ---
                % 1. Get the sus_par struct from the workspace
                susStruct = evalin('base', 'sus_par');
                fields = fieldnames(susStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything into text format
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = susStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. arrays (like damper curves)
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Safely handles arrays/vectors
                    end
                end
                
                % 4. Push to the UI Table
                app.SuspensionTable.Data = tableData;
                app.SuspensionTable.ColumnName = {'Parameter', 'Value'};

                %% Create table for Aerodynamics parameters
                % --- LOAD AERO PARAMETERS (aero_par) ---
                % 1. Get the aero_par struct from the workspace
                aeroStruct = evalin('base', 'aero_par');
                fields = fieldnames(aeroStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything into text format
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = aeroStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. arrays (like aero maps)
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Safely handles matrices/vectors
                    end
                end
                
                % 4. Push to the UI Table
                app.AeroTable.Data = tableData;
                app.AeroTable.ColumnName = {'Parameter', 'Value'};

                %% Create Table for Motor parameters
                % --- LOAD MOTOR PARAMETERS (Motor) ---
                % 1. Get the Motor struct from the workspace
                motorStruct = evalin('base', 'Motor');
                fields = fieldnames(motorStruct);
                
                % 2. Create empty data array for the Table
                tableData = cell(length(fields), 2);
                
                % 3. Loop through and extract everything into text format
                for i = 1:length(fields)
                    paramName = fields{i};
                    val = motorStruct.(paramName);
                    
                    tableData{i, 1} = paramName;
                    
                    % Handle text vs. numbers vs. arrays (like efficiency maps or torque curves)
                    if ischar(val) || isstring(val)
                        tableData{i, 2} = char(val); 
                    elseif isscalar(val) && isnumeric(val)
                        tableData{i, 2} = num2str(val); 
                    else
                        tableData{i, 2} = mat2str(val); % Safely handles matrices/vectors
                    end
                end

                % 4. Push to the UI Table
                app.MotorTable.Data = tableData;
                app.MotorTable.ColumnName = {'Parameter', 'Value'};
                app.Lamp.Color = 'red';
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        value = app.Switch.Value;

        if strcmp(value,"Simulink")
            assignin('base','Mode',1);
            app.BrowseCSVButton.Enable = "off";
            app.CSV_FileEditField.Visible = "off";
            app.TimeseriesButton.Enable = 'off';
            app.CSV_FilenameLabel.Visible = "off";

        elseif strcmp(value,"Timeseries")
            app.TimeseriesButton.Enable = "on";
            assignin('base','Mode',2);
        else
            assignin('base','Mode',1);
        end
        end

        % Button pushed function: TimeseriesButton
        function TimeseriesButtonPushed(app, event)
            assignin("base","Mode",2)
            app.BrowseCSVButton.Enable = "on";
            app.BrowseCSVButton.Visible = "on";
            app.CSV_FileEditField.Enable = "on";
            
        end

        % Button pushed function: LoadtirfileButton
        function LoadtirfileButtonPushed(app, event)
             [file, path] = uigetfile('*.tir', 'Select TIR file');

            % User pressed Cancel
            if isequal(file,0)
                return;
            end
            
            fullFileName = fullfile(path, file);
            loadTireDataFromTIR(fullFileName)
            
        end

        % Button pushed function: BrowseCSVButton
        function BrowseCSVButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select CSV file');

            % User pressed Cancel
            if isequal(file,0)
                return;
            end
            
            fullFileName = fullfile(path, file);
            % Show path in UI
            app.CSV_FileEditField.Value = fullFileName;
            app.CSV_FilenameLabel.Visible = "on";
            app.CSV_FileEditField.Visible = "on";  

            data = readtable(fullFileName);
            throttle_data = data.Throttle_pct/100;
            throttle_ts = timeseries(throttle_data,data.Time);
            steer_ts = timeseries(data.Steering*pi/180,data.Time);
            brake_ts = timeseries(data.Brake,data.Time);

            assignin("base","brake_ts",brake_ts);
            assignin("base","steer_ts",steer_ts);
            assignin("base","throttle_ts",throttle_ts);
        end

        % Button pushed function: PlotTireButton
        function PlotTireButtonPushed(app, event)
            % % 1. Lock the UI
            % app.PlotTireButton.Enable = 'off'; % Change to your actual button name!
            % drawnow;
            % 
            % try
            %     % --- 2. SETUP TILED LAYOUT ---
            %     delete(app.TirePanel.Children); 
            % 
            %     % Create a 2x4 grid (2 rows, 4 columns)
            %     tireLayout = tiledlayout(app.TirePanel, 2, 4);
            %     tireLayout.TileSpacing = 'compact';
            %     tireLayout.Padding = 'compact';
            % 
            %     % Set panel background to dark grey/black
            %     app.TirePanel.BackgroundColor = [0.03, 0.03, 0.03]; 
            % 
            %     % --- 3. RUN SIMULATION ---
            %     assignin("base", "Mode", 1);
            %     out = runSimulation(app.modelname);
            % 
            %     if isempty(out)
            %         error('Simulink ran, but returned empty data.');
            %     end
            % 
            %     % --- 4. EXTRACT & GROUP DATA ---
            %     % Longitudinal Data (Fx vs Slip Ratio K)
            %     K_data = {squeeze(out.slip_ratio_fl.Data), squeeze(out.slip_ratio_fr.Data), ...
            %               squeeze(out.slip_ratio_rl.Data), squeeze(out.slip_ratio_rr.Data)};
            %     Fx_data = {squeeze(out.Fx_fl.Data), squeeze(out.Fx_fr.Data), ...
            %                squeeze(out.Fx_rl.Data), squeeze(out.Fx_rr.Data)};
            % 
            %     % Lateral Data (Fy vs Slip Angle Alpha)
            %     alpha_data = {squeeze(out.slip_angle_fl.Data), squeeze(out.slip_angle_fr.Data), ...
            %                   squeeze(out.slip_angle_rl.Data), squeeze(out.slip_angle_rr.Data)};
            %     Fy_data = {squeeze(out.Fy_fl.Data), squeeze(out.Fy_fr.Data), ...
            %                squeeze(out.Fy_rl.Data), squeeze(out.Fy_rr.Data)};
            % 
            %     % Labels
            %     wheel_names = {'Front Left (FL)', 'Front Right (FR)', 'Rear Left (RL)', 'Rear Right (RR)'};
            % 
            %     % --- 5. HIGH-CONTRAST NEON THEME ---
            %     bgColor = [0.03, 0.03, 0.03];    
            %     gridColor = [0.20, 0.20, 0.20];  
            %     colorLongitudinal = [0, 1, 1]; % Neon Cyan for Fx
            %     colorLateral = [1, 0, 1];      % Neon Magenta for Fy
            % 
            %     % --- 6. PLOT ROW 1: LONGITUDINAL (Fx vs K) ---
            %     for i = 1:4
            %         ax = nexttile(tireLayout);
            % 
            %         ax.Color = bgColor;
            %         ax.XColor = [0.9, 0.9, 0.9]; 
            %         ax.YColor = [0.9, 0.9, 0.9];
            %         ax.GridColor = gridColor;
            %         ax.GridAlpha = 0.8;
            %         grid(ax, 'on');
            %         ax.Box = 'on'; 
            % 
            %         hold(ax, 'on');
            %         % Transparent scatter for heatmap effect
            %         scatter(ax, K_data{i}, Fx_data{i}, 10, ...
            %             'MarkerFaceColor', colorLongitudinal, ...
            %             'MarkerEdgeColor', 'none', ...
            %             'MarkerFaceAlpha', 0.5); 
            %         hold(ax, 'off');
            % 
            %         title(ax, [wheel_names{i} ' - Fx vs \kappa'], 'Color', 'white', 'FontSize', 11, 'FontWeight', 'bold');
            %         xlabel(ax, 'Slip Ratio (\kappa)', 'Color', [0.8, 0.8, 0.8]);
            %         if i == 1
            %             ylabel(ax, 'Longitudinal Force Fx (N)', 'Color', [0.8, 0.8, 0.8]);
            %         end
            %     end
            % 
            %     % --- 7. PLOT ROW 2: LATERAL (Fy vs Alpha) ---
            %     for i = 1:4
            %         ax = nexttile(tireLayout);
            % 
            %         ax.Color = bgColor;
            %         ax.XColor = [0.9, 0.9, 0.9]; 
            %         ax.YColor = [0.9, 0.9, 0.9];
            %         ax.GridColor = gridColor;
            %         ax.GridAlpha = 0.8;
            %         grid(ax, 'on');
            %         ax.Box = 'on'; 
            % 
            %         hold(ax, 'on');
            %         % Transparent scatter for heatmap effect
            %         scatter(ax, alpha_data{i}, Fy_data{i}, 10, ...
            %             'MarkerFaceColor', colorLateral, ...
            %             'MarkerEdgeColor', 'none', ...
            %             'MarkerFaceAlpha', 0.5); 
            %         hold(ax, 'off');
            % 
            %         title(ax, [wheel_names{i} ' - Fy vs \alpha'], 'Color', 'white', 'FontSize', 11, 'FontWeight', 'bold');
            %         xlabel(ax, 'Slip Angle \alpha (rad)', 'Color', [0.8, 0.8, 0.8]);
            %         if i == 1
            %             ylabel(ax, 'Lateral Force Fy (N)', 'Color', [0.8, 0.8, 0.8]);
            %         end
            %     end
            % 
            %     % Unlock the UI
            %     app.PlotTireButton.Enable = 'on'; 
            % 
            % catch ME
            %     app.PlotTireButton.Enable = 'on'; 
            %     uialert(app.UIFigure, ME.message, 'Plotting Error');
            % end
            app.PlotTireButton.Enable = 'off';
            app.Lamp.Color = 'green';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Call specialized methods
            app.plotTireAnalysis(out);            
            app.Lamp.Color = "red";
            app.PlotTireButton.Enable = 'on';

        end

        % Button pushed function: PlotMotorButton
        function PlotMotorButtonPushed(app, event)
            % % 1. Lock the UI
            % app.PlotMotorButton.Enable = 'off'; % Change this to your actual button name!
            % drawnow;
            % 
            % try
            %     % --- 2. SETUP TILED LAYOUT ---
            %     % Clears the panel in case you click it multiple times
            %     delete(app.MotorPanel.Children); 
            % 
            %     % Create a 2x2 grid
            %     motLayout = tiledlayout(app.MotorPanel, 2, 2);
            %     motLayout.TileSpacing = 'compact';
            %     motLayout.Padding = 'compact';
            % 
            %     % Set panel background to dark grey/black
            %     app.MotorPanel.BackgroundColor = [0.03, 0.03, 0.03]; 
            % 
            %     % --- 3. RUN SIMULATION & EXTRACT DATA ---
            %     out = runSimulation(app.modelname);
            % 
            %     if isempty(out)
            %         error('Simulink ran, but returned empty data.');
            %     end
            % 
            %     % Extract variables exactly as you had them
            %     motor_rpm = squeeze(out.motor_rpm.Data);
            %     motor_torque = squeeze(out.motor_torque.Data);
            %     P_mech = squeeze(out.P_mech.Data);
            %     current = squeeze(out.current.Data);
            % 
            %     % --- 4. HIGH-CONTRAST NEON THEME ---
            %     bgColor = [0.03, 0.03, 0.03];    
            %     gridColor = [0.20, 0.20, 0.20];  
            % 
            %     % 3 Distinct Neon Colors: Cyan, Magenta, Lime Green
            %     neonColors = {[0, 1, 1], [1, 0, 1], [0.39, 1.0, 0.0]}; 
            % 
            %     % Group data for easy loop plotting
            %     x_data = {motor_rpm, motor_rpm, current};
            %     y_data = {motor_torque, P_mech, motor_torque};
            %     plot_titles = {'Motor Torque vs Speed', 'Mechanical Power vs Speed', 'Motor Torque vs Current'};
            %     x_labels = {'Speed (RPM)', 'Speed (RPM)', 'Current (A)'};
            %     y_labels = {'Torque (Nm)', 'Power (W)', 'Torque (Nm)'};
            % 
            %     % --- 5. PLOT THE DATA ---
            %     for i = 1:3
            %         % PRO-TRICK: Make the 3rd plot span 1 row and 2 columns!
            %         if i == 3
            %             ax = nexttile(motLayout, [1 2]); 
            %         else
            %             ax = nexttile(motLayout);
            %         end
            % 
            %         % Apply dark theme to axes
            %         ax.Color = bgColor;
            %         ax.XColor = [0.9, 0.9, 0.9]; 
            %         ax.YColor = [0.9, 0.9, 0.9];
            %         ax.GridColor = gridColor;
            %         ax.GridAlpha = 0.8;
            %         grid(ax, 'on');
            %         ax.Box = 'on'; 
            % 
            %         hold(ax, 'on');
            % 
            %         % Plot using scatter for the glowing telemetry look
            %         scatter(ax, x_data{i}, y_data{i}, 10, ...
            %             'MarkerFaceColor', neonColors{i}, ...
            %             'MarkerEdgeColor', 'none', ...
            %             'MarkerFaceAlpha', 0.5); 
            % 
            %         hold(ax, 'off');
            % 
            %         % Add crisp labels
            %         title(ax, plot_titles{i}, 'Color', 'white', 'FontSize', 12, 'FontWeight', 'bold');
            %         xlabel(ax, x_labels{i}, 'Color', [0.8, 0.8, 0.8]);
            %         ylabel(ax, y_labels{i}, 'Color', [0.8, 0.8, 0.8]);
            %     end
            % 
            %     % Unlock the UI
            %     app.PlotMotorButton.Enable = 'on'; 
            % 
            % catch ME
            %     app.PlotMotorButton.Enable = 'on'; 
            %     uialert(app.UIFigure, ME.message, 'Plotting Error');
            % end
            app.PlotMotorButton.Enable = 'off';
            app.Lamp.Color = 'green';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Call specialized methods
            app.plotMotorAnalysis(out);            
            app.Lamp.Color = "red";
            app.PlotMotorButton.Enable = 'on';
        end

        % Button pushed function: PlotBatteryButton
        function PlotBatteryButtonPushed(app, event)
           % % 1. Lock the UI
           %  app.PlotBatteryButton.Enable = 'off'; % Change this to your actual button name!
           %  drawnow;
           % 
           %  try
           %      % --- 2. SETUP TILED LAYOUT ---
           %      % Clears the panel in case you click it multiple times
           %      delete(app.BatteryPanel.Children); 
           % 
           %      % Create the 2x2 grid
           %      batLayout = tiledlayout(app.BatteryPanel, 2, 2);
           %      batLayout.TileSpacing = 'compact';
           %      batLayout.Padding = 'compact';
           % 
           %      % Set panel background to dark grey/black
           %      app.BatteryPanel.BackgroundColor = [0.03, 0.03, 0.03]; 
           % 
           %      % --- 3. RUN SIMULATION & EXTRACT DATA ---
           %      out = runSimulation(app.modelname);
           % 
           %      if isempty(out)
           %          error('Simulink ran, but returned empty data.');
           %      end
           % 
           %      SoC = squeeze(out.SoC.Data);
           %      R0 = squeeze(out.R0_cell.Data);
           %      R1 = squeeze(out.R1_cell.Data);
           %      V0 = squeeze(out.cell_volt.Data);
           %      Tou_1 = squeeze(out.Tou_1.Data);
           % 
           %      % Group data for the loop
           %      y_data = {R0, R1, V0, Tou_1};
           %      plot_titles = {'R0 vs SoC', 'R1 vs SoC', 'Cell Voltage vs SoC', 'Tau_1 vs SoC'};
           %      y_labels = {'Resistance (Ohms)', 'Resistance (Ohms)', 'Voltage (V)', 'Time Constant (s)'};
           % 
           %      % --- 4. HIGH-CONTRAST NEON THEME ---
           %      bgColor = [0.03, 0.03, 0.03];    
           %      gridColor = [0.20, 0.20, 0.20];  
           % 
           %      % 4 Distinct Neon Colors for the 4 plots:
           %      % Cyan, Magenta, Lime Green, and Electric Yellow
           %      neonColors = {[0, 1, 1], [1, 0, 1], [0.39, 1.0, 0.0], [1, 1, 0]}; 
           % 
           %      % --- 5. PLOT THE DATA ---
           %      for i = 1:4
           %          ax = nexttile(batLayout);
           % 
           %          % Apply dark theme to axes
           %          ax.Color = bgColor;
           %          ax.XColor = [0.9, 0.9, 0.9]; 
           %          ax.YColor = [0.9, 0.9, 0.9];
           %          ax.GridColor = gridColor;
           %          ax.GridAlpha = 0.8;
           %          grid(ax, 'on');
           %          ax.Box = 'on'; 
           % 
           %          hold(ax, 'on');
           % 
           %          % Plot using scatter for that glowing UI look
           %          % Size is 10, Alpha is 0.5 for transparency
           %          scatter(ax, SoC, y_data{i}, 10, ...
           %              'MarkerFaceColor', neonColors{i}, ...
           %              'MarkerEdgeColor', 'none', ...
           %              'MarkerFaceAlpha', 0.5); 
           % 
           %          hold(ax, 'off');
           % 
           %          % Add crisp labels
           %          title(ax, plot_titles{i}, 'Color', 'white', 'FontSize', 12, 'FontWeight', 'bold');
           %          xlabel(ax, 'State of Charge (SoC)', 'Color', [0.8, 0.8, 0.8]);
           %          ylabel(ax, y_labels{i}, 'Color', [0.8, 0.8, 0.8]);
           % 
           %          % Optional Pro-Tip: Battery SoC usually drains from 1 to 0. 
           %          % If you want the X-axis to visually read right-to-left as it drains, uncomment the line below:
           %          % ax.XDir = 'reverse'; 
           %      end
           % 
           %      % Unlock the UI
           %      app.PlotBatteryButton.Enable = 'on'; 
           % 
           %  catch ME
           %      app.PlotBatteryButton.Enable = 'on'; 
           %      uialert(app.UIFigure, ME.message, 'Plotting Error');
           %  end
            app.PlotBatteryButton.Enable = 'off';
            app.Lamp.Color = 'green';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Call specialized methods
            app.plotBatteryAnalysis(out);           
            app.Lamp.Color = "red";
            app.PlotBatteryButton.Enable = 'on';
        end

        % Button pushed function: PlotGGVButton
        function PlotGGVButtonPushed(app, event)
            
            cla(app.UIAxes3D) % clear previous plot 
            app.Lamp.Color = 'green';
            assignin("base",'Mode',0)
            app.PleasewaitLabel.Visible = "on";
            app.GGVplotgeneratedLabel.Visible = "off";

            [results, Ax_grid, Ay_grid, V_grid] = runGGV_Master_3D(app.modelname);
            
            % ---- 3D plot ----
            
            surf(app.UIAxes3D, Ax_grid, Ay_grid, V_grid, ...
                'FaceAlpha',0.5,'EdgeColor','interp')
            
            colormap(app.UIAxes3D,'jet')
            colorbar(app.UIAxes3D)
            
            xlabel(app.UIAxes3D,'Longitudinal G')
            ylabel(app.UIAxes3D,'Lateral G')
            zlabel(app.UIAxes3D,'Speed (m/s)')
            title(app.UIAxes3D,'3D Performance Envelope')
            
            grid(app.UIAxes3D,'on')
            axis(app.UIAxes3D,'equal')
            view(app.UIAxes3D,135,30)
            app.PleasewaitLabel.Visible = "off";
            app.GGVplotgeneratedLabel.Visible = "on";
            app.Lamp.Color = 'red';


        end

        % Button pushed function: PlotYaw_Moment_diagramButton
        function PlotYaw_Moment_diagramButtonPushed(app, event)
            app.PleasewaitLabel_2.Visible = "on"; 
            app.Lamp.Color = 'green';
            TireData = evalin("base","TireData");
            [res_Ay, res_Mz, Steer_Deg, Beta_Deg] = yaw_moment_diagram(app.Par, app.sus_par, app.aero_par, TireData, false);

            % Plot in UIAxes
            ax = app.UIAxes3D_2;
            cla(ax); hold(ax,'on'); grid(ax,'on');
            
            cmap = turbo(length(Steer_Deg));
            
            for i = 1:length(Steer_Deg)
            
                [ay_sorted, idx] = sort(res_Ay(i,:));
                plot(ax, ay_sorted, res_Mz(i,idx), ...
                    'Color', cmap(i,:), 'LineWidth', 2);
            
                % ---- ADD ANGLE LABELS (THIS WAS MISSING) ----
                if mod(Steer_Deg(i),4) == 0
                    text(ax, ...
                        ay_sorted(end), ...
                        res_Mz(i,idx(end)), ...
                        sprintf('%d°', Steer_Deg(i)), ...
                        'Color', cmap(i,:), ...
                        'FontSize', 10, ...
                        'FontWeight','bold', ...
                        'HorizontalAlignment','left');
                end
            end
            
            yline(ax, 0, 'r--', 'LineWidth', 2);
            
            xlabel(ax,'Lateral Acceleration (a_y / g)');
            ylabel(ax,'Normalized Yaw Moment');
            title(ax,'Hybrid Stabilized YMD');
            
            xlim(ax,[-2.5 2.5]);
            ylim(ax,[-1.2 1.2]);
            app.PleasewaitLabel_2.Visible = "off";
            app.YawmomentdiagramplottedLabel.Visible = "on";
            app.Lamp.Color = 'red';

        end

        % Button pushed function: PlotCurvaturevsSteer_angleButton
        function PlotCurvaturevsSteer_angleButtonPushed(app, event)
            cla(app.UIAxes15)  % clear previous plot
            app.Lamp.Color = 'green';
            assignin("base","Mode",3)
            velocities = 1:5:21;

            hold(app.UIAxes15,'on');
            grid(app.UIAxes15,'on');
            title(app.UIAxes15,"Curvature vs Steering Angle (multiple velocities)")
            xlabel(app.UIAxes15,"Steering Angle (deg)")
            ylabel(app.UIAxes15,"Curvature (1/R)")

            for i = 1:length(velocities)

            results = plotCurvatureVsSteer_MultiV(app.modelname,velocities(i));               

            plot(app.UIAxes15, results.s_deg, results.kappa, '.', ...
            'DisplayName', sprintf("V = %g m/s", results.v));
                
            legend(app.UIAxes15,'show','Location','best');
            end
            hold(app.UIAxes15,'off');
            app.Lamp.Color = 'red';

        end

        % Button pushed function: PlotGGButton
        function PlotGGButtonPushed(app, event)
        app.PlotGGButton.Enable = 'off';
        app.Lamp.Color = 'green'; 
        drawnow;   
        
        % 1. Run the Simulation
        assignin("base", "Mode", 1); 
        out = runSimulation(app.modelname);
        
        % ---> ADD THIS LINE <---
        % This forces a copy of the simulation data into your main workspace!
        assignin("base", "out", out); 
        
        % 2. Call the plotting method
        app.plotGGAnalysis(out);            
        
        app.Lamp.Color = "red";
        app.PlotGGButton.Enable = 'on';

        end

        % Button pushed function: plot_mapButton
        function plot_mapButtonPushed(app, event)
            cla(app.UIAxes20)  % clear app axis
            app.TheplotisunderprogressLabel.Visible = "on";
            hold(app.UIAxes20,"on")

            % assignin("base","Mode",4)  % Switching between modes for different outputs.
            % results1 = plotsideslipvscurvature1(app.modelname);
            % plot(app.UIAxes20,results1.kappa,results1.sideslip,"r.")
            % 
            % assignin("base","Mode",5)
            % results2 = plotsideslipvscurvature2(app.modelname);
            % plot(app.UIAxes20,results2.kappa,results2.sideslip,"b.")
            % hold(app.UIAxes20,"off")

        end

        % Button pushed function: Plot_handlingcurveButton
        function Plot_handlingcurveButtonPushed(app, event)
            cla(app.UIAxes21)
            app.Lamp.Color = 'green';
            assignin("base","Mode",6)

            results3 = plot_handling_curve(app.modelname);

            diff = results3.steer_L - results3.steer_R;

            plot(app.UIAxes21,diff,results3.ay,"b.")
            xlabel(app.UIAxes21,"steering angle difference")
            ylabel(app.UIAxes21,"lateral accln")
            title(app.UIAxes21,"Handling curve")
            app.Lamp.Color = 'red';
        end

        % Button pushed function: Plot_Lapsim_enduranceButton
        function Plot_Lapsim_enduranceButtonPushed(app, event)
            % --- 1. PREPARE THE UI ---
            % Change cursor to a watch so the user knows it's calculating
            app.Lamp.Color = 'green';
            app.UIFigure.Pointer = 'watch';
            drawnow;
            
            try
                % --- 2. DEFINE INPUTS & RUN SIMULATION ---
                % (Update these paths or pull them from app dropdowns/edit fields if needed)
                track_file = app.TrackFilePath_endurance; 
                ggv_file   = 'GGV_Final_Data.mat';
                
                % Assuming Par and aero_par are stored in app properties, e.g., app.Par, app.aero_par
                % If they are workspace variables, you might need to load them first.
                % SimResults = Run_Endurance_Simulation(track_file, ggv_file, app.Par, app.aero_par);
                
                % For this script's sake, assuming Par and aero_par are available:
                SimResults = LapSim_Endurance_for_app(track_file, ggv_file, app.Par, app.aero_par);
            
                % --- 3. PREPARE THE DASHBOARD PANEL ---
                app.LaptimesimulatorforEndurancePanel.BackgroundColor = 'k';
                delete(app.LaptimesimulatorforEndurancePanel.Children); % Wipe old plots if the user clicks "Run" again
            
                % 0. Extract Data and Set Limits for consistent coloring
                v_kph = SimResults.v * 3.6;
                v_min_kph = min(v_kph);
                v_max_kph = max(v_kph);
                c_limits  = [v_min_kph, v_max_kph]; 
            
                % Create the Tiled Layout INSIDE app.LaptimesimulatorforEndurancePanel
                t = tiledlayout(app.LaptimesimulatorforEndurancePanel, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
            
                % =========================================================================
                % --- PLOT 1: TRACK MAP (LEFT LARGE) ---
                ax1 = nexttile(t, 1, [2 1]); 
                hold(ax1, 'on'); 
                axis(ax1, 'equal'); 
                grid(ax1, 'on');
                set(ax1, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
            
                plot(ax1, SimResults.Track.xl_edge, SimResults.Track.yl_edge, 'w-', 'LineWidth', 0.5);
                plot(ax1, SimResults.Track.xr_edge, SimResults.Track.yr_edge, 'w-', 'LineWidth', 0.5);
            
                z = zeros(size(SimResults.Path.X));
                surface(ax1, ...
                        [SimResults.Path.X'; SimResults.Path.X'], ...
                        [SimResults.Path.Y'; SimResults.Path.Y'], ...
                        [z'; z'], [v_kph'; v_kph'], ...
                        'FaceColor', 'no', 'EdgeColor', 'interp', 'LineWidth', 3);
                        
                colormap(ax1, 'turbo'); 
                clim(ax1, c_limits);         
                c = colorbar(ax1, 'southoutside'); 
                c.Color = 'w'; c.Label.Color = 'w'; c.Label.String = 'Speed [km/h]';
                title(ax1, sprintf('Endurance: %d Laps', SimResults.Num_Laps), 'Color', 'w', 'FontSize', 12);
                xlabel(ax1, 'X [m]', 'Color', 'w'); ylabel(ax1, 'Y [m]', 'Color', 'w');
            
                % =========================================================================
                % --- PLOT 2: SPEED TRACE (TOP MIDDLE) ---
                ax2 = nexttile(t, 2);
                plot(ax2, SimResults.s, v_kph, 'LineWidth', 2, 'Color', '#4DBEEE'); 
                grid(ax2, 'on'); 
                xlim(ax2, [0 max(SimResults.s)]);
                set(ax2, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                ylabel(ax2, 'Speed [km/h]', 'Color', 'w'); 
                title(ax2, sprintf('Flying Lap: %.2fs', SimResults.LapTime_FL), 'Color', 'w');
            
                % =========================================================================
                % --- PLOT 3: POWER TRACE (TOP RIGHT) ---
                ax3 = nexttile(t, 3);
                area(ax3, SimResults.s, SimResults.Power_kW, 'FaceColor', '#77AC30', 'FaceAlpha', 0.5, 'EdgeColor', 'w');
                grid(ax3, 'on'); 
                xlim(ax3, [0 max(SimResults.s)]);
                set(ax3, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                ylabel(ax3, 'Power [kW]', 'Color', 'w'); 
                title(ax3, sprintf('Total Energy: %.2f kWh', SimResults.Total_Energy_kWh), 'Color', 'w');
                xlabel(ax3, 'Distance [m]', 'Color', 'w');
            
                % =========================================================================
                % --- PLOT 4: STEERING TRACE (BOTTOM MIDDLE) ---
                ax4 = nexttile(t, 5);
                plot(ax4, SimResults.s, SimResults.steer_deg, 'LineWidth', 1.5, 'Color', '#D95319');
                grid(ax4, 'on'); 
                xlim(ax4, [0 max(SimResults.s)]);
                set(ax4, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                ylabel(ax4, 'Steer [deg]', 'Color', 'w');
                xlabel(ax4, 'Distance [m]', 'Color', 'w'); 
            
                % =========================================================================
                % --- PLOT 5: G-G USAGE DIAGRAM (BOTTOM RIGHT) ---
                ax5 = nexttile(t, 6);
                hold(ax5, 'on'); 
                grid(ax5, 'on'); 
                axis(ax5, 'equal');
                set(ax5, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
            
                scatter(ax5, SimResults.ay_used/9.81, SimResults.ax_used/9.81, 15, v_kph, 'filled');
            
                xlabel(ax5, 'Lat G', 'Color', 'w'); ylabel(ax5, 'Long G', 'Color', 'w');
                title(ax5, 'G-G Diagram', 'Color', 'w');
                xlim(ax5, [-3 3]); ylim(ax5, [-3 3]); 
                colormap(ax5, 'turbo'); clim(ax5, c_limits);
            
            catch ME
                % If something breaks (like a missing file), pop up an error box
                uialert(app.UIFigure, ME.message, 'Simulation Error', 'Icon', 'error');
            end
            
            % --- 4. CLEANUP ---
            % Reset cursor back to normal arrow
            app.UIFigure.Pointer = 'arrow';
            app.Lamp.Color = 'red';
        end

        % Button pushed function: Browse_track_csv_for_EnduranceButton
        function Browse_track_csv_for_EnduranceButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Track CSV File');
    
            % If the user presses Cancel, exit
            if isequal(file, 0)
                return;
            end
            
            % Store the full file path in the app's hidden property
            app.TrackFilePath_endurance = fullfile(path, file);
            
            % Visual feedback: Change the button text and color to show success
            event.Source.Text = 'Track Selected!';
            event.Source.BackgroundColor = [0.47, 0.67, 0.19]; % Nice green color
            event.Source.FontColor = 'w';
        end

        % Button pushed function: Browse_track_csvButton
        function Browse_track_csvButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Track CSV File');
    
            % If the user presses Cancel, exit
            if isequal(file, 0)
                return;
            end
            
            % Store the full file path in the app's hidden property
            app.TrackFilePath_final = fullfile(path, file);
            
            % Visual feedback: Change the button text and color to show success
            event.Source.Text = 'Track Selected!';
            event.Source.BackgroundColor = [0.47, 0.67, 0.19]; % Nice green color
            event.Source.FontColor = 'w';
        end

        % Button pushed function: Plot_LapSim_finalButton
        function Plot_LapSim_finalButtonPushed(app, event)
            % --- 1. RUN SIMULATION & EXTRACT DATA ---
            % Change pointer to watch to show calculation is running
            app.Lamp.Color = 'green';
            app.UIFigure.Pointer = 'watch';
            drawnow;
            track_file = app.TrackFilePath_final;
            ggv_file = "GGV_Final_Data.mat";
            try
                SimResults = Lapsim_final_for_app(track_file, ggv_file, app.Par, app.TireData);
                
                % Define Global Color Limits (CRITICAL FOR CONSISTENCY)
                v_kph = SimResults.v * 3.6;
                v_min_kph = min(v_kph);
                v_max_kph = max(v_kph);
                c_limits  = [v_min_kph, v_max_kph]; 
            
                % --- 2. PREPARE THE DASHBOARD PANEL ---
                app.FinalLaptimesimulatorPanel.BackgroundColor = 'k';
                delete(app.FinalLaptimesimulatorPanel.Children); % Wipe old plots to prevent overlap
            
                % Create the Tiled Layout INSIDE app.FinalLaptimesimulatorPanel
                t = tiledlayout(app.FinalLaptimesimulatorPanel, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
            
                % =========================================================================
                % --- PLOT 1: TRACK MAP (LEFT LARGE) ---
                ax1 = nexttile(t, 1, [2 1]); 
                hold(ax1, 'on'); 
                axis(ax1, 'equal'); 
                grid(ax1, 'on');
                set(ax1, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
            
                % Plot raw track edges 
                plot(ax1, SimResults.Track.xl_edge, SimResults.Track.yl_edge, 'w-', 'LineWidth', 0.5);
                plot(ax1, SimResults.Track.xr_edge, SimResults.Track.yr_edge, 'w-', 'LineWidth', 0.5);
            
                % Plot Colored Racing Line using the 3D surface trick
                z = zeros(size(SimResults.Path.X));
                surface(ax1, ...
                        [SimResults.Path.X'; SimResults.Path.X'], ...
                        [SimResults.Path.Y'; SimResults.Path.Y'], ...
                        [z'; z'], [v_kph'; v_kph'], ...
                        'FaceColor', 'no', 'EdgeColor', 'interp', 'LineWidth', 3);
                        
                colormap(ax1, 'turbo'); 
                clim(ax1, c_limits);         
                c = colorbar(ax1, 'southoutside'); 
                c.Color = 'w'; c.Label.Color = 'w'; c.Label.String = 'Speed [km/h]';
                title(ax1, 'Optimal Racing Line', 'Color', 'w', 'FontSize', 14);
                xlabel(ax1, 'X [m]'); ylabel(ax1, 'Y [m]');
            
                % =========================================================================
                % --- PLOT 2: SPEED TRACE (TOP MIDDLE) ---
                ax2 = nexttile(t, 2);
                plot(ax2, SimResults.s, v_kph, 'LineWidth', 2, 'Color', '#4DBEEE'); 
                grid(ax2, 'on'); 
                xlim(ax2, [0 max(SimResults.s)]);
                set(ax2, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                ylabel(ax2, 'Speed [km/h]', 'Color', 'w', 'FontWeight', 'bold'); 
                xlabel(ax2, 'Distance [m]', 'Color', 'w'); 
                title(ax2, sprintf('Lap Time: %.2fs', SimResults.LapTime), 'Color', 'w');
            
                % =========================================================================
                % --- PLOT 3: GGV USAGE vs TIRE LIMITS (TOP RIGHT) ---
                ax3 = nexttile(t, 3);
                hold(ax3, 'on'); 
                grid(ax3, 'on'); 
                axis(ax3, 'equal');
                set(ax3, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
            
                % Generate Theoretical Tire Limit Ellipse
                theta = linspace(0, 2*pi, 100);
                tire_lim_lat  = SimResults.TireLimits.mu_y_peak * cos(theta); 
                tire_lim_long = SimResults.TireLimits.mu_x_peak * sin(theta); 
            
                % Plot Elements
                plot(ax3, tire_lim_lat, tire_lim_long, 'm--', 'LineWidth', 2, 'DisplayName', 'Tire Potential');
                scatter(ax3, SimResults.ay_used/9.81, SimResults.ax_used/9.81, 20, v_kph, 'filled', 'DisplayName', 'Lap Data');
                
                xlabel(ax3, 'Lat G', 'Color', 'w'); ylabel(ax3, 'Long G', 'Color', 'w');
                title(ax3, 'Transient Usage vs Tire Limit', 'Color', 'w');
                legend(ax3, 'Location', 'best', 'TextColor', 'w', 'Color', 'none', 'EdgeColor', 'none');
                xlim(ax3, [-3.0 3.0]); ylim(ax3, [-3.0 3.0]); 
                colormap(ax3, 'turbo'); clim(ax3, c_limits);
            
                % =========================================================================
                % --- PLOT 4: STEERING TRACE (BOTTOM MIDDLE) ---
                ax4 = nexttile(t, 5);
                plot(ax4, SimResults.s, SimResults.steer_deg, 'LineWidth', 1.5, 'Color', '#D95319');
                grid(ax4, 'on'); 
                xlim(ax4, [0 max(SimResults.s)]);
                set(ax4, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                ylabel(ax4, 'Steer [deg]', 'Color', 'w', 'FontWeight', 'bold');
                xlabel(ax4, 'Distance [m]', 'Color', 'w'); 
                title(ax4, 'Steering Input', 'Color', 'w');
            
                % =========================================================================
                % --- PLOT 5: 3D PERFORMANCE TUBE (BOTTOM RIGHT) ---
                ax5 = nexttile(t, 6);
                hold(ax5, 'on'); 
                grid(ax5, 'on');
            
                % Create Mesh from Envelope
                Env = SimResults.GGV_Env; 
                [V_mesh, Theta_mesh] = meshgrid(linspace(min(Env.v), max(Env.v), 40), linspace(0, 2*pi, 40));
                Ax_surf = zeros(size(V_mesh));
                Ay_surf = zeros(size(V_mesh));
            
                % Build the "Tube"
                for r = 1:size(V_mesh, 1)
                    for c = 1:size(V_mesh, 2)
                        v_val = V_mesh(r,c);
                        th = Theta_mesh(r,c);
                        
                        ay_lim = interp1(Env.v, Env.ay_max, v_val, 'linear', 'extrap') / 9.81;
                        ax_acc = interp1(Env.v, Env.ax_acc, v_val, 'linear', 'extrap') / 9.81;
                        ax_brk = abs(interp1(Env.v, Env.ax_brk, v_val, 'linear', 'extrap') / 9.81);
                        
                        if sin(th) >= 0, r_long = ax_acc; else, r_long = ax_brk; end
                        rad = (r_long * ay_lim) / sqrt((ay_lim * sin(th))^2 + (r_long * cos(th))^2);
                        
                        Ay_surf(r,c) = rad * cos(th); % Lateral
                        Ax_surf(r,c) = rad * sin(th); % Longitudinal
                    end
                end
            
                % Plot Surface and Actual Lap Data
                surf(ax5, Ay_surf, Ax_surf, V_mesh * 3.6, 'FaceAlpha', 0.1, 'EdgeColor', 'none', 'FaceColor', 'w');
                scatter3(ax5, SimResults.ay_used/9.81, SimResults.ax_used/9.81, v_kph, 25, v_kph, 'filled');
                view(ax5, 135, 30);
                set(ax5, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'ZColor', 'w', 'GridColor', 'w', 'GridAlpha', 0.2);
                xlabel(ax5, 'Lat G', 'Color', 'w'); ylabel(ax5, 'Long G', 'Color', 'w'); zlabel(ax5, 'Speed [km/h]', 'Color', 'w');
                title(ax5, '3D Performance Envelope', 'Color', 'w');
                colormap(ax5, 'turbo'); clim(ax5, c_limits);
            
            catch ME
                % If the simulation errors out, show a popup inside the app
                uialert(app.UIFigure, ME.message, 'Simulation Error', 'Icon', 'error');
            end
            
            % --- 3. CLEANUP ---
            % Reset cursor back to normal arrow
            app.UIFigure.Pointer = 'arrow';
            app.Lamp.Color = 'red';
        end

        % Cell edit callback: BatteryTable
        function BatteryTableCellEdit(app, event)
            % --- Inside app.BatteryTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.BatteryTable.Data{row, 1};
            newStrVal = event.NewData; % The user's new typed value
            
            try
                % 1. Get current struct from workspace
                battStruct = evalin('base', 'Battery');
                oldVal = battStruct.(paramName);
                
                % 2. Check if the original parameter was text or a number/matrix
                if ischar(oldVal) || isstring(oldVal)
                    battStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a math number/matrix
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    battStruct.(paramName) = newNumVal; % Save as number
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'Battery', battStruct);
                
            catch
                % If they type gibberish instead of a number, revert it
                app.BatteryTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format (e.g., [1 2 3]).', 'Input Error');
            end
        end

        % Cell edit callback: TireTable
        function TireTableCellEdit(app, event)
        % --- Inside app.TireTable CellEditCallback ---
        row = event.Indices(1);
        paramName = app.TireTable.Data{row, 1};
        newStrVal = event.NewData;
        
        try
            % 1. Get current struct from workspace
            tireStruct = evalin('base', 'TireData');
            
            % 2. Check if the user edited a nested 'Scales' parameter
            if startsWith(paramName, 'Scales.')
                % Strip the 'Scales.' prefix to get the real variable name (e.g., 'LMUX')
                subField = strrep(paramName, 'Scales.', '');
                
                % Convert and save to the nested struct
                newNumVal = str2num(newStrVal);
                if isempty(newNumVal), error('Invalid numeric format.'); end
                tireStruct.Scales.(subField) = newNumVal;
                
            else
                % It's a standard root-level parameter
                oldVal = tireStruct.(paramName);
                
                if ischar(oldVal) || isstring(oldVal)
                    tireStruct.(paramName) = newStrVal; 
                else
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal), error('Invalid numeric format.'); end
                    tireStruct.(paramName) = newNumVal; 
                end
            end
            
            % 3. Push the updated struct back to the Base Workspace
            assignin('base', 'TireData', tireStruct);
            
        catch
            % Revert visually if they type an invalid character
            app.TireTable.Data{row, 2} = event.PreviousData;
            uialert(app.UIFigure, 'Please enter a valid numeric format.', 'Input Error');
        end
        end

        % Cell edit callback: ParTable
        function ParTableCellEdit(app, event)
            % --- Inside app.ParTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.ParTable.Data{row, 1};
            newStrVal = event.NewData; % The user's new typed value
            
            try
                % 1. Get current struct from workspace
                parStruct = evalin('base', 'Par');
                oldVal = parStruct.(paramName);
                
                % 2. Check data type and convert the new string back to math
                if ischar(oldVal) || isstring(oldVal)
                    parStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a number or array
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    parStruct.(paramName) = newNumVal; % Save as math
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'Par', parStruct);
                
            catch
                % Revert visually if the user types an invalid character
                app.ParTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format (e.g., [1.53]).', 'Input Error');
            end
        end

        % Cell edit callback: BrakeTable
        function BrakeTableCellEdit(app, event)
            % --- Inside app.BrakeTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.BrakeTable.Data{row, 1};
            newStrVal = event.NewData; % The user's newly typed value
            
            try
                % 1. Get current struct from workspace
                brakeStruct = evalin('base', 'Brake');
                oldVal = brakeStruct.(paramName);
                
                % 2. Check data type and convert the new string back to math
                if ischar(oldVal) || isstring(oldVal)
                    brakeStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a number or array
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    brakeStruct.(paramName) = newNumVal; % Save as math
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'Brake', brakeStruct);
                
            catch
                % Revert visually if the user types an invalid character (like letters instead of numbers)
                app.BrakeTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format.', 'Input Error');
            end
        end

        % Cell edit callback: SuspensionTable
        function SuspensionTableCellEdit(app, event)
            % --- Inside app.SuspensionTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.SuspensionTable.Data{row, 1};
            newStrVal = event.NewData; % The user's newly typed value
            
            try
                % 1. Get current struct from workspace
                susStruct = evalin('base', 'sus_par');
                oldVal = susStruct.(paramName);
                
                % 2. Check data type and convert the new string back to math
                if ischar(oldVal) || isstring(oldVal)
                    susStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a number or array
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    susStruct.(paramName) = newNumVal; % Save as math
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'sus_par', susStruct);
                
            catch
                % Revert visually if the user types an invalid character
                app.SuspensionTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format.', 'Input Error');
            end
        end

        % Cell edit callback: AeroTable
        function AeroTableCellEdit(app, event)
            % --- Inside app.AeroTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.AeroTable.Data{row, 1};
            newStrVal = event.NewData; % The user's newly typed value
            
            try
                % 1. Get current struct from workspace
                aeroStruct = evalin('base', 'aero_par');
                oldVal = aeroStruct.(paramName);
                
                % 2. Check data type and convert the new string back to math
                if ischar(oldVal) || isstring(oldVal)
                    aeroStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a number or array
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    aeroStruct.(paramName) = newNumVal; % Save as math
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'aero_par', aeroStruct);
                
            catch
                % Revert visually if the user types an invalid character
                app.AeroTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format.', 'Input Error');
            end
        end

        % Cell edit callback: MotorTable
        function MotorTableCellEdit(app, event)
            % --- Inside app.MotorTable CellEditCallback ---
            row = event.Indices(1);
            paramName = app.MotorTable.Data{row, 1};
            newStrVal = event.NewData; % The user's newly typed value
            
            try
                % 1. Get current struct from workspace
                motorStruct = evalin('base', 'Motor');
                oldVal = motorStruct.(paramName);
                
                % 2. Check data type and convert the new string back to math
                if ischar(oldVal) || isstring(oldVal)
                    motorStruct.(paramName) = newStrVal; % Save as text
                else
                    % Convert the string back into a number or array
                    newNumVal = str2num(newStrVal); 
                    if isempty(newNumVal)
                        error('Invalid numeric format.');
                    end
                    motorStruct.(paramName) = newNumVal; % Save as math
                end
                
                % 3. Push the updated struct back to the Base Workspace
                assignin('base', 'Motor', motorStruct);
                
            catch
                % Revert visually if the user types an invalid character
                app.MotorTable.Data{row, 2} = event.PreviousData;
                uialert(app.UIFigure, 'Please enter a valid number or array format.', 'Input Error');
            end
        end

        % Button pushed function: PlotallButton
        function PlotallButtonPushed(app, event)
                app.Lamp.Color = 'green';
                app.PlotallButton.Enable = 'off';
                drawnow;
            
                try
                    % 1. Run Simulation
                    assignin("base", "Mode", 1);
                    out = runSimulation(app.modelname);
                    
                    % 2. Call specialized methods
                    app.plotTireAnalysis(out);
                    app.plotMotorAnalysis(out);
                    app.plotBatteryAnalysis(out);
                    app.plotOutputsTelemetry(out);
                    app.plotBrakingSystem(out);
                    app.plotInputPanel(out);
                    app.plotWheelDisplacement(out);
                    app.plotRollAndPitch(out);
                    app.plotAeroAnalysis(out);
                    app.plotGGAnalysis(out);            
            
                    uialert(app.UIFigure, 'All dashboards and GGV animation updated.', 'Success');
                    
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Error');
                    end
                    
                    app.PlotallButton.Enable = 'on';
                    app.Lamp.Color = 'red';
           
        end

        % Button pushed function: LoadplotsforInputsButton
        function LoadplotsforInputsButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.LoadplotsforInputsButton.Enable = 'off';
            drawnow;
            
            % Clear everything inside the panel
            delete(app.InputPanel.Children)
            
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Plot new graphs
            app.plotInputPanel(out);
            
            app.LoadplotsforInputsButton.Enable = 'on';
            app.Lamp.Color = 'red';
        end

        % Button pushed function: BrakePlotsButton
        function BrakePlotsButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.BrakePlotsButton.Enable = 'off';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
                
            % 2. Call specialized methods
            app.plotBrakingSystem(out);
            app.BrakePlotsButton.Enable = 'on';
            app.Lamp.Color = 'red';
                
        end

        % Button pushed function: PlotsusButton
        function PlotsusButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.BrakePlotsButton.Enable = 'off';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Call specialized methods
            app.plotRollAndPitch(out);
            app.plotWheelDisplacement(out);
            app.BrakePlotsButton.Enable = 'on';
            app.Lamp.Color = 'red';
        end

        % Button pushed function: GenerateAeroPlotsButton
        function GenerateAeroPlotsButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.GenerateAeroPlotsButton.Enable = 'off';
            drawnow;   
            % 1. Run Simulation
            assignin("base", "Mode", 1);
            out = runSimulation(app.modelname);
            
            % 2. Call specialized methods
            app.plotAeroAnalysis(out)            
            app.GenerateAeroPlotsButton.Enable = 'on';
            app.Lamp.Color = 'red';
        end

        % Button pushed function: PlotoutputsButton
        function PlotoutputsButtonPushed(app, event)
            app.Lamp.Color = 'green';
                app.PlotallButton.Enable = 'off';
                drawnow;
            try
                % 1. Run Simulation
                assignin("base", "Mode", 1);
                out = runSimulation(app.modelname);
                app.plotOutputsTelemetry(out);
            end
                
                app.PlotallButton.Enable = 'on';
                app.Lamp.Color = 'red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.TimeseriesButton = uibutton(app.HomeTab, 'push');
            app.TimeseriesButton.ButtonPushedFcn = createCallbackFcn(app, @TimeseriesButtonPushed, true);
            app.TimeseriesButton.Text = 'Timeseries';
            app.CSV_FileEditFieldLabel = uilabel(app.HomeTab);
            app.CSV_FileEditFieldLabel.Visible = 'off';
            app.CSV_FileEditFieldLabel.Text = 'CSV_File';
            app.CSV_FileEditField = uieditfield(app.HomeTab, 'text');
            app.CSV_FileEditField.Editable = 'off';
            app.CSV_FileEditField.Visible = 'off';
            app.BrowseCSVButton = uibutton(app.HomeTab, 'push');
            app.BrowseCSVButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCSVButtonPushed, true);
            app.BrowseCSVButton.Visible = 'off';
            app.BrowseCSVButton.Text = 'Browse CSV';
            app.CSV_FilenameLabel = uilabel(app.HomeTab);
            app.CSV_FilenameLabel.Visible = 'off';
            app.CSV_FilenameLabel.Text = 'CSV_Filename:';
            app.SelecttimeseriesifyouhavecsvfileofinputsLabel = uilabel(app.HomeTab);
            app.SelecttimeseriesifyouhavecsvfileofinputsLabel.Text = 'Select timeseries if you have csv file of inputs';
            app.Switch = uiswitch(app.HomeTab, 'slider');
            app.Switch.Items = {'Simulink', 'Timeseries'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Simulink';
            app.LoadtirfileButton = uibutton(app.HomeTab, 'push');
            app.LoadtirfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtirfileButtonPushed, true);
            app.LoadtirfileButton.Text = 'Load .tir file';
            app.VehicleparametersandInputsTab = uitab(app.TabGroup);
            app.VehicleparametersandInputsTab.Title = 'Vehicle parameters and Inputs';
            app.InputPanel = uipanel(app.VehicleparametersandInputsTab);
            app.InputPanel.Title = 'Inputs Torque and Steering';
            app.LoadplotsforInputsButton = uibutton(app.VehicleparametersandInputsTab, 'push');
            app.LoadplotsforInputsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadplotsforInputsButtonPushed, true);
            app.LoadplotsforInputsButton.Text = 'Load plots for Inputs';
            app.ParTable = uitable(app.VehicleparametersandInputsTab);
            app.ParTable.ColumnName = {'Column 1'; 'Column 2'};
            app.ParTable.RowName = {};
            app.ParTable.ColumnEditable = [false true];
            app.ParTable.CellEditCallback = createCallbackFcn(app, @ParTableCellEdit, true);
            app.Tire_analysisTab_2 = uitab(app.TabGroup);
            app.Tire_analysisTab_2.Title = 'Tire_analysis';
            app.PlotTireButton = uibutton(app.Tire_analysisTab_2, 'push');
            app.PlotTireButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTireButtonPushed, true);
            app.PlotTireButton.Text = 'Plot Tire Button';
            app.TirePanel = uipanel(app.Tire_analysisTab_2);
            app.TirePanel.Title = 'Plots for Tire';
            app.TireTable = uitable(app.Tire_analysisTab_2);
            app.TireTable.ColumnName = {'Column 1'; 'Column 2'};
            app.TireTable.RowName = {};
            app.TireTable.ColumnEditable = [false true];
            app.TireTable.CellEditCallback = createCallbackFcn(app, @TireTableCellEdit, true);
            app.LoadtirfileButton_2 = uibutton(app.Tire_analysisTab_2, 'push');
            app.LoadtirfileButton_2.Text = 'Load .tir file';
            app.BrakesTab = uitab(app.TabGroup);
            app.BrakesTab.Title = 'Brakes';
            app.BrakeTable = uitable(app.BrakesTab);
            app.BrakeTable.ColumnName = {'Column 1'; 'Column 2'};
            app.BrakeTable.RowName = {};
            app.BrakeTable.ColumnEditable = [false true];
            app.BrakeTable.CellEditCallback = createCallbackFcn(app, @BrakeTableCellEdit, true);
            app.BrakeTorquePanel = uipanel(app.BrakesTab);
            app.BrakeTorquePanel.Title = 'Front and Rear Braking Torque';
            app.Brake_ipt_pct = uipanel(app.BrakesTab);
            app.Brake_ipt_pct.Title = 'Brake input percent';
            app.BrakePlotsButton = uibutton(app.BrakesTab, 'push');
            app.BrakePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @BrakePlotsButtonPushed, true);
            app.BrakePlotsButton.Text = 'Brake Plots';
            app.SuspensionsTab = uitab(app.TabGroup);
            app.SuspensionsTab.Title = 'Suspensions';
            app.SuspensionTable = uitable(app.SuspensionsTab);
            app.SuspensionTable.ColumnName = {'Column 1'; 'Column 2'};
            app.SuspensionTable.RowName = {};
            app.SuspensionTable.ColumnEditable = [false true];
            app.SuspensionTable.CellEditCallback = createCallbackFcn(app, @SuspensionTableCellEdit, true);
            app.WheelDisplacementPanel = uipanel(app.SuspensionsTab);
            app.WheelDisplacementPanel.Title = 'Vertical displacement of each wheel';
            app.RollandPitchPanel = uipanel(app.SuspensionsTab);
            app.RollandPitchPanel.Title = 'Body roll and Pitch';
            app.PlotsusButton = uibutton(app.SuspensionsTab, 'push');
            app.PlotsusButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsusButtonPushed, true);
            app.PlotsusButton.Text = 'Plot sus';
            app.AerodynamicsTab = uitab(app.TabGroup);
            app.AerodynamicsTab.Title = 'Aerodynamics';
            app.AeroTable = uitable(app.AerodynamicsTab);
            app.AeroTable.ColumnName = {'Column 1'; 'Column 2'};
            app.AeroTable.RowName = {};
            app.AeroTable.ColumnEditable = [false true];
            app.AeroTable.CellEditCallback = createCallbackFcn(app, @AeroTableCellEdit, true);
            app.AeroplotsPanel = uipanel(app.AerodynamicsTab);
            app.AeroplotsPanel.Title = 'Panel';
            app.GenerateAeroPlotsButton = uibutton(app.AerodynamicsTab, 'push');
            app.GenerateAeroPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAeroPlotsButtonPushed, true);
            app.GenerateAeroPlotsButton.Text = 'Generate Aero Plots';
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.PlotMotorButton = uibutton(app.MotorTab, 'push');
            app.PlotMotorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMotorButtonPushed, true);
            app.PlotMotorButton.Text = 'Plot Motor Button';
            app.MotorTable = uitable(app.MotorTab);
            app.MotorTable.ColumnName = {'Column 1'; 'Column 2'};
            app.MotorTable.RowName = {};
            app.MotorTable.ColumnEditable = [false true];
            app.MotorTable.CellEditCallback = createCallbackFcn(app, @MotorTableCellEdit, true);
            app.MotorPanel = uipanel(app.MotorTab);
            app.MotorPanel.Title = 'Plots for motor';
            app.BatteryTab = uitab(app.TabGroup);
            app.BatteryTab.Title = 'Battery';
            app.PlotBatteryButton = uibutton(app.BatteryTab, 'push');
            app.PlotBatteryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBatteryButtonPushed, true);
            app.PlotBatteryButton.Text = 'Plot Battery Button';
            app.BatteryTable = uitable(app.BatteryTab);
            app.BatteryTable.ColumnName = {'Column 1'; 'Column 2'};
            app.BatteryTable.RowName = {};
            app.BatteryTable.ColumnEditable = [false true];
            app.BatteryTable.CellEditCallback = createCallbackFcn(app, @BatteryTableCellEdit, true);
            app.BatteryPanel = uipanel(app.BatteryTab);
            app.BatteryPanel.Title = 'Plots for Battery';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsPanel = uipanel(app.OutputsTab);
            app.OutputsPanel.Title = 'Outputs';
            app.PlotoutputsButton = uibutton(app.OutputsTab, 'push');
            app.PlotoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotoutputsButtonPushed, true);
            app.PlotoutputsButton.Text = 'Plot outputs';
            app.gg_per_wheelTab = uitab(app.TabGroup);
            app.gg_per_wheelTab.Title = 'gg_per_wheel';
            app.PlotGGButton = uibutton(app.gg_per_wheelTab, 'push');
            app.PlotGGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGGButtonPushed, true);
            app.PlotGGButton.Text = 'Plot GG';
            app.GGV_per_wheelPanel = uipanel(app.gg_per_wheelTab);
            app.GGV_per_wheelPanel.Title = 'GG diagram per wheel';
            app.GGVandYawmomentTab = uitab(app.TabGroup);
            app.GGVandYawmomentTab.Title = 'GGV and Yawmoment';
            app.UIAxes3D = uiaxes(app.GGVandYawmomentTab);
            title(app.UIAxes3D, 'GGV Plot')
            app.UIAxes3D_2 = uiaxes(app.GGVandYawmomentTab);
            title(app.UIAxes3D_2, 'Yaw moment Diagram')
            xlabel(app.UIAxes3D_2, 'Lateral acceleration')
            ylabel(app.UIAxes3D_2, 'Yaw moment')
            zlabel(app.UIAxes3D_2, 'Z')
            app.PlotGGVButton = uibutton(app.GGVandYawmomentTab, 'push');
            app.PlotGGVButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGGVButtonPushed, true);
            app.PlotGGVButton.Text = 'Plot GGV';
            app.PleasewaitLabel = uilabel(app.GGVandYawmomentTab);
            app.PleasewaitLabel.Visible = 'off';
            app.PleasewaitLabel.Text = 'Please wait...';
            app.GGVplotgeneratedLabel = uilabel(app.GGVandYawmomentTab);
            app.GGVplotgeneratedLabel.Visible = 'off';
            app.GGVplotgeneratedLabel.Text = 'GGV plot generated';
            app.PlotYaw_Moment_diagramButton = uibutton(app.GGVandYawmomentTab, 'push');
            app.PlotYaw_Moment_diagramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotYaw_Moment_diagramButtonPushed, true);
            app.PlotYaw_Moment_diagramButton.Text = 'Plot Yaw_Moment_diagram';
            app.PleasewaitLabel_2 = uilabel(app.GGVandYawmomentTab);
            app.PleasewaitLabel_2.Visible = 'off';
            app.PleasewaitLabel_2.Text = 'Please wait...';
            app.YawmomentdiagramplottedLabel = uilabel(app.GGVandYawmomentTab);
            app.YawmomentdiagramplottedLabel.Visible = 'off';
            app.YawmomentdiagramplottedLabel.Text = 'Yaw moment diagram plotted';
            app.MAPsTab = uitab(app.TabGroup);
            app.MAPsTab.Title = 'MAPs';
            app.UIAxes15 = uiaxes(app.MAPsTab);
            title(app.UIAxes15, 'Curvature vs Steering angle')
            xlabel(app.UIAxes15, 'steering angle')
            ylabel(app.UIAxes15, 'Curvature')
            zlabel(app.UIAxes15, 'Z')
            app.UIAxes20 = uiaxes(app.MAPsTab);
            title(app.UIAxes20, 'Sideslip vs curvature')
            xlabel(app.UIAxes20, 'Curvature')
            ylabel(app.UIAxes20, 'Sideslip angle')
            zlabel(app.UIAxes20, 'Z')
            app.PlotCurvaturevsSteer_angleButton = uibutton(app.MAPsTab, 'push');
            app.PlotCurvaturevsSteer_angleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurvaturevsSteer_angleButtonPushed, true);
            app.PlotCurvaturevsSteer_angleButton.Text = 'Plot Curvature vs Steer_angle';
            app.plot_mapButton = uibutton(app.MAPsTab, 'push');
            app.plot_mapButton.ButtonPushedFcn = createCallbackFcn(app, @plot_mapButtonPushed, true);
            app.plot_mapButton.Text = 'plot_map';
            app.TheplotisunderprogressLabel = uilabel(app.MAPsTab);
            app.TheplotisunderprogressLabel.Text = 'The plot  is under progress ';
            app.handlingcurveTab = uitab(app.TabGroup);
            app.handlingcurveTab.Title = 'handling curve';
            app.UIAxes21 = uiaxes(app.handlingcurveTab);
            title(app.UIAxes21, 'Handling curve')
            xlabel(app.UIAxes21, 'Steering angle difference')
            ylabel(app.UIAxes21, 'Lateral acceleration')
            zlabel(app.UIAxes21, 'Z')
            app.Plot_handlingcurveButton = uibutton(app.handlingcurveTab, 'push');
            app.Plot_handlingcurveButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_handlingcurveButtonPushed, true);
            app.Plot_handlingcurveButton.Text = 'Plot_handling curve';
            app.LapSim_EnduranceTab = uitab(app.TabGroup);
            app.LapSim_EnduranceTab.Title = 'LapSim_Endurance';
            app.LaptimesimulatorforEndurancePanel = uipanel(app.LapSim_EnduranceTab);
            app.LaptimesimulatorforEndurancePanel.Title = 'Laptime simulator for Endurance';
            app.Browse_track_csv_for_EnduranceButton = uibutton(app.LapSim_EnduranceTab, 'push');
            app.Browse_track_csv_for_EnduranceButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_track_csv_for_EnduranceButtonPushed, true);
            app.Browse_track_csv_for_EnduranceButton.Text = 'Browse_track_csv_for_Endurance';
            app.Plot_Lapsim_enduranceButton = uibutton(app.LapSim_EnduranceTab, 'push');
            app.Plot_Lapsim_enduranceButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_Lapsim_enduranceButtonPushed, true);
            app.Plot_Lapsim_enduranceButton.Text = 'Plot_Lapsim_endurance';
            app.LapSim_AutocrossTab = uitab(app.TabGroup);
            app.LapSim_AutocrossTab.Title = 'LapSim_Autocross';
            app.FinalLaptimesimulatorPanel = uipanel(app.LapSim_AutocrossTab);
            app.FinalLaptimesimulatorPanel.Title = 'Final Laptime simulator';
            app.Browse_track_csvButton = uibutton(app.LapSim_AutocrossTab, 'push');
            app.Browse_track_csvButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_track_csvButtonPushed, true);
            app.Browse_track_csvButton.Text = 'Browse_track_csv';
            app.Plot_LapSim_finalButton = uibutton(app.LapSim_AutocrossTab, 'push');
            app.Plot_LapSim_finalButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_LapSim_finalButtonPushed, true);
            app.Plot_LapSim_finalButton.Text = 'Plot_LapSim_final';
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.Lamp = uilamp(app.UIFigure);
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.PlotallButton = uibutton(app.UIFigure, 'push');
            app.PlotallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallButtonPushed, true);
            app.PlotallButton.Text = 'Plot all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app12

            % Associate the Simulink Model
            app.Simulation = simulation('Vehicle_Model_999_Modular');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end